classdef CRO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PlotButton                   matlab.ui.control.Button
        FreqKnobLabel                matlab.ui.control.Label
        FreqKnob                     matlab.ui.control.Knob
        AmplitudeKnobLabel           matlab.ui.control.Label
        AmplitudeKnob                matlab.ui.control.Knob
        TypeofWaveformDropDownLabel  matlab.ui.control.Label
        TypeofWaveformDropDown       matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            ax=app.UIAxes;
            t=0:0.01:5*pi;
           if isequal(app.TypeofWaveformDropDown.Value, 'SINE')
            y=app.AmplitudeKnob.Value*sin(2*pi*app.FreqKnob.Value.*t);
            else
          y=app.AmplitudeKnob.Value*square(2*pi*app.FreqKnob.Value.*t);
            end
            plot(ax,t,y);
            
        end

        % Value changed function: FreqKnob
        function FreqKnobValueChanged(app, event)
           
        end

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            
        end

        % Value changed function: TypeofWaveformDropDown
        function TypeofWaveformDropDownValueChanged(app, event)
       
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FreqKnobLabel = uilabel(app.UIFigure);
            app.FreqKnobLabel.Text = 'Freq';
            app.FreqKnob = uiknob(app.UIFigure, 'continuous');
            app.FreqKnob.Limits = [0 3];
            app.FreqKnob.ValueChangedFcn = createCallbackFcn(app, @FreqKnobValueChanged, true);
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 10];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.TypeofWaveformDropDownLabel = uilabel(app.UIFigure);
            app.TypeofWaveformDropDownLabel.Text = 'TypeofWaveformDropDown';
            app.TypeofWaveformDropDown = uidropdown(app.UIFigure);
            app.TypeofWaveformDropDown.Items = {'SINE', 'SQUARE', '', ''};
            app.TypeofWaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofWaveformDropDownValueChanged, true);
            app.TypeofWaveformDropDown.Value = 'SINE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CRO

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end