classdef Ledlamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DegFEditFieldLabel  matlab.ui.control.Label
        DegFEditField       matlab.ui.control.NumericEditField
        DegCEditFieldLabel  matlab.ui.control.Label
        DegCEditField       matlab.ui.control.NumericEditField
        LEDLampLabel        matlab.ui.control.Label
        LEDLamp             matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Value changed function: DegFEditField
        function DegFEditFieldValueChanged(app, event)
            value = app.DegFEditField.Value;
             app.DegCEditField.Value = (app.DegFEditField.Value-32)*0.5555;
             
        end

        % Value changed function: DegCEditField
        function DegCEditFieldValueChanged(app, event)
            app.DegFEditField.Value = (1.8*app.DegCEditField.Value) + 32;
            if (app.DegFEditField.Value > 100)
                 app.LEDLamp.Enable=1; 
             else 
                app.LEDLamp.Enable=0;
             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DegFEditFieldLabel = uilabel(app.UIFigure);
            app.DegFEditFieldLabel.Text = 'Deg F';
            app.DegFEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegFEditField.ValueChangedFcn = createCallbackFcn(app, @DegFEditFieldValueChanged, true);
            app.DegCEditFieldLabel = uilabel(app.UIFigure);
            app.DegCEditFieldLabel.Text = 'Deg C';
            app.DegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegCEditField.ValueChangedFcn = createCallbackFcn(app, @DegCEditFieldValueChanged, true);
            app.LEDLampLabel = uilabel(app.UIFigure);
            app.LEDLampLabel.Text = 'LED';
            app.LEDLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Ledlamp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end